classdef Circuit_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button_6                    matlab.ui.control.Button
        Button_16                   matlab.ui.control.Button
        Button_15                   matlab.ui.control.Button
        Button_14                   matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Button_10                   matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        Panel                       matlab.ui.container.Panel
        wEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel             matlab.ui.control.Label
        numberEditField             matlab.ui.control.NumericEditField
        numberEditFieldLabel        matlab.ui.control.Label
        Button_5                    matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        node1node1node2Panel        matlab.ui.container.Panel
        sEditField                  matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        node2EditField_2            matlab.ui.control.NumericEditField
        node2EditField_2Label       matlab.ui.control.Label
        node1EditField_2            matlab.ui.control.NumericEditField
        node1EditField_2Label       matlab.ui.control.Label
        fEditField                  matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        nameEditField               matlab.ui.control.EditField
        nameEditFieldLabel          matlab.ui.control.Label
        valueEditField              matlab.ui.control.NumericEditField
        valueLabel                  matlab.ui.control.Label
        Button_9                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        initialphaseEditField       matlab.ui.control.NumericEditField
        initialphaseEditFieldLabel  matlab.ui.control.Label
        Button_2                    matlab.ui.control.Button
        LinenumberEditField_2       matlab.ui.control.NumericEditField
        LinenumberEditField_2Label  matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        node2EditField              matlab.ui.control.NumericEditField
        node2EditFieldLabel         matlab.ui.control.Label
        node1EditField              matlab.ui.control.NumericEditField
        node1EditFieldLabel         matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        UIAxes2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t % Description
        u % Description
    end
    
    properties (Access = public)
        Rnumber=0 % Description 电阻的数目
        Vnumber=0 % Description 电压源的数目
        Inumber=0% Description 电流源的数目
        Cnumber=0%电容的数目
        Lnumber=0%电感的数目
        IInumber=0
        IVnumber=0
        VVnumber=0
        VInumber=0
        number=0 % 结点数
        w=0;%电路频率
        RNP=[] % 每一行由每个电阻的结点1 、结点2 、阻值构成的矩阵
        VNP=[] % 每一行由每个电压源的结点1、结点2、值构成的矩阵
        INP=[] % 每一行由每个电流源的结点1、结点2、值构成的矩阵
        CNP=[]% 每一行由每个电容的结点1、结点2、值构成的矩阵
        LNP=[]% 每一行由每个电感的结点1、结点2、值构成的矩阵
        IINP=[]
        VVNP=[]
        IVNP=[]
        VINP=[]
        r=[]
        ii=[]
        uu=[]
        c=[]
        l=[]% r、ii、uu、c、l都只是中间变量，没有意义
        iii=[]
        iv=[]
        vv=[]
        vi=[]
        U=[] % 最后得出的结点电压列向量
        n=[]
        ss=str2sym("x")
    end

    methods (Access = public)
        function results = zhiliu(app)
            N=app.number;
            GMatrix=zeros(N,N);
            IMatrix=zeros(N,1);
            for j=1:1:app.Rnumber
                GMatrix(app.RNP(j,1),app.RNP(j,1))=GMatrix(app.RNP(j,1),app.RNP(j,1))+(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,2))=GMatrix(app.RNP(j,2),app.RNP(j,2))+(1/app.RNP(j,3));
                GMatrix(app.RNP(j,1),app.RNP(j,2))=GMatrix(app.RNP(j,1),app.RNP(j,2))-(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,1))=GMatrix(app.RNP(j,2),app.RNP(j,1))-(1/app.RNP(j,3));
            end
            for j=1:1:app.IInumber
                GMatrix(app.IINP(j,2),app.IINP(j,3))=GMatrix(app.IINP(j,2),app.IINP(j,3))-app.IINP(j,5)*GMatrix(app.IINP(j,3),app.IINP(j,4));
                GMatrix(app.IINP(j,2),app.IINP(j,4))=GMatrix(app.IINP(j,2),app.IINP(j,4))+app.IINP(j,5)*GMatrix(app.IINP(j,3),app.IINP(j,4));
                GMatrix(app.IINP(j,1),app.IINP(j,3))=GMatrix(app.IINP(j,1),app.IINP(j,3))+app.IINP(j,5)*GMatrix(app.IINP(j,3),app.IINP(j,4));
                GMatrix(app.IINP(j,1),app.IINP(j,4))=GMatrix(app.IINP(j,1),app.IINP(j,4))-app.IINP(j,5)*GMatrix(app.IINP(j,3),app.IINP(j,4));
            end
            for j=1:1:app.IVnumber
                GMatrix(app.IVNP(j,2),app.IVNP(j,3))=GMatrix(app.IVNP(j,2),app.IVNP(j,3))-app.IVNP(j,5);
                GMatrix(app.IVNP(j,2),app.IVNP(j,4))=GMatrix(app.IVNP(j,2),app.IVNP(j,4))+app.IVNP(j,5);
                GMatrix(app.IVNP(j,1),app.IVNP(j,3))=GMatrix(app.IVNP(j,1),app.IVNP(j,3))+app.IVNP(j,5);
                GMatrix(app.IVNP(j,1),app.IVNP(j,4))=GMatrix(app.IVNP(j,1),app.IVNP(j,4))-app.IVNP(j,5);
            end
            for j=1:1:app.Inumber
                IMatrix(app.INP(j,2),1)=IMatrix(app.INP(j,2),1)+app.INP(j,3);%终止点
                IMatrix(app.INP(j,1),1)=IMatrix(app.INP(j,1),1)-app.INP(j,3);%起始点
            end
            for j=1:1:app.Vnumber
                if GMatrix(app.VNP(j,1),app.VNP(j,2))~=0
                    IMatrix(app.VNP(j,1),1)=IMatrix(app.VNP(j,1),1)-app.VNP(j,3)*GMatrix(app.VNP(j,1),app.VNP(j,2));
                end
                if GMatrix(app.VNP(j,2),app.VNP(j,1))~=0
                    IMatrix(app.VNP(j,2),1)=IMatrix(app.VNP(j,2),1)+app.VNP(j,3)*GMatrix(app.VNP(j,2),app.VNP(j,1));
                end
                if GMatrix(app.VNP(j,1),app.VNP(j,2))==0
                    GMatrix(app.VNP(j,2),:)=GMatrix(app.VNP(j,1),:)+GMatrix(app.VNP(j,2),:);
                    GMatrix(app.VNP(j,1),:)=0;
                    IMatrix(app.VNP(j,1),:)=0;
                    tracer=app.VNP(j,1);
                    GMatrix(tracer,app.VNP(j,1))=GMatrix(tracer,app.VNP(j,1))+1;
                    GMatrix(tracer,app.VNP(j,2))=GMatrix(tracer,app.VNP(j,2))-1;
                    IMatrix(tracer,1)=IMatrix(tracer,1)+app.VNP(j,3);
                end
            end
            
            for j=1:1:app.VVnumber
                if GMatrix(app.VVNP(j,1),app.VVNP(j,2))~=0
                GMatrix(app.VVNP(j,2),app.VVNP(j,3))=GMatrix(app.VVNP(j,2),app.VVNP(j,3))-app.VVNP(j,5)*GMatrix(app.VVNP(j,1),app.VVNP(j,2));
                GMatrix(app.VVNP(j,2),app.VVNP(j,4))=GMatrix(app.VVNP(j,2),app.VVNP(j,4))+app.VVNP(j,5)*GMatrix(app.VVNP(j,1),app.VVNP(j,2));
                GMatrix(app.VVNP(j,1),app.VVNP(j,3))=GMatrix(app.VVNP(j,1),app.VVNP(j,3))+app.VVNP(j,5)*GMatrix(app.VVNP(j,1),app.VVNP(j,2));
                GMatrix(app.VVNP(j,1),app.VVNP(j,4))=GMatrix(app.VVNP(j,1),app.VVNP(j,4))-app.VVNP(j,5)*GMatrix(app.VVNP(j,1),app.VVNP(j,2));
                end
                if GMatrix(app.VVNP(j,1),app.VVNP(j,2))==0
                    GMatrix(app.VVNP(j,2),:)=GMatrix(app.VVNP(j,1),:)+GMatrix(app.VVNP(j,2),:);
                    GMatrix(app.VVNP(j,1),:)=0;
                    IMatrix(app.VVNP(j,1),:)=0;
                    tracer=app.VVNP(j,1);
                    GMatrix(tracer,app.VVNP(j,1))=GMatrix(tracer,app.VVNP(j,1))+1;
                    GMatrix(tracer,app.VVNP(j,2))=GMatrix(tracer,app.VVNP(j,2))-1;
                    GMatrix(tracer,app.VVNP(j,4))=GMatrix(tracer,app.VVNP(j,4))+app.VVNP(j,5);
                    GMatrix(tracer,app.VVNP(j,3))=GMatrix(tracer,app.VVNP(j,3))-app.VVNP(j,5);
                end
            end
            for j=1:1:app.VInumber
                if GMatrix(app.VINP(j,1),app.VINP(j,2))~=0
                GMatrix(app.VINP(j,2),app.VINP(j,3))=GMatrix(app.VINP(j,2),app.VINP(j,3))-app.VINP(j,5)*GMatrix(app.VINP(j,1),app.VINP(j,2))*GMatrix(app.VINP(j,3),app.VINP(j,4));
                GMatrix(app.VINP(j,2),app.VINP(j,4))=GMatrix(app.VINP(j,2),app.VINP(j,4))+app.VINP(j,5)*GMatrix(app.VINP(j,1),app.VINP(j,2))*GMatrix(app.VINP(j,3),app.VINP(j,4));
                GMatrix(app.VINP(j,1),app.VINP(j,3))=GMatrix(app.VINP(j,1),app.VINP(j,3))+app.VINP(j,5)*GMatrix(app.VINP(j,1),app.VINP(j,2))*GMatrix(app.VINP(j,3),app.VINP(j,4));
                GMatrix(app.VINP(j,1),app.VINP(j,4))=GMatrix(app.VINP(j,1),app.VINP(j,4))-app.VINP(j,5)*GMatrix(app.VINP(j,1),app.VINP(j,2))*GMatrix(app.VINP(j,3),app.VINP(j,4));
                end
                if GMatrix(app.VINP(j,1),app.VINP(j,2))==0
                    GMatrix(app.VINP(j,2),:)=GMatrix(app.VINP(j,1),:)+GMatrix(app.VINP(j,2),:);
                    GMatrix(app.VINP(j,1),:)=0;
                    IMatrix(app.VINP(j,1),:)=0;
                    tracer=app.VINP(j,1);
                    GMatrix(tracer,app.VINP(j,1))=GMatrix(tracer,app.VINP(j,1))+1;
                    GMatrix(tracer,app.VINP(j,2))=GMatrix(tracer,app.VINP(j,2))-1;
                    GMatrix(tracer,app.VINP(j,4))=GMatrix(tracer,app.VINP(j,4))+app.VINP(j,5)*GMatrix(app.VINP(j,3),app.VINP(j,4));
                    GMatrix(tracer,app.VINP(j,3))=GMatrix(tracer,app.VINP(j,3))-app.VINP(j,5)*GMatrix(app.VINP(j,3),app.VINP(j,4));
                end
            end
            GMatrix2=zeros(N-1,N-1);
            for f=1:1:N-1
                for ff=1:1:N-1
                    GMatrix2(f,ff)=GMatrix(f,ff);
                end
            end
            IMatrix2=zeros(N-1,1);
            for m=1:1:N-1
                IMatrix2(m,1)=IMatrix(m,1);
            end
            UMatrix=pinv(GMatrix2)*IMatrix2;
            results=UMatrix;
        end
        
        function results = zhengxian(app)
            N=app.number;
            GMatrix=zeros(N,N);
            IMatrix=zeros(N,1);
            for j=1:1:app.Rnumber
                GMatrix(app.RNP(j,1),app.RNP(j,1))=GMatrix(app.RNP(j,1),app.RNP(j,1))+(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,2))=GMatrix(app.RNP(j,2),app.RNP(j,2))+(1/app.RNP(j,3));
                GMatrix(app.RNP(j,1),app.RNP(j,2))=GMatrix(app.RNP(j,1),app.RNP(j,2))-(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,1))=GMatrix(app.RNP(j,2),app.RNP(j,1))-(1/app.RNP(j,3));
            end
            for j=1:1:app.Cnumber
                GMatrix(app.CNP(j,1),app.CNP(j,1))=GMatrix(app.CNP(j,1),app.CNP(j,1))+1i*app.w*app.CNP(j,3)*10^(-6);
                GMatrix(app.CNP(j,2),app.CNP(j,2))=GMatrix(app.CNP(j,2),app.CNP(j,2))+1i*app.w*app.CNP(j,3)*10^(-6);
                GMatrix(app.CNP(j,1),app.CNP(j,2))=GMatrix(app.CNP(j,1),app.CNP(j,2))-1i*app.w*app.CNP(j,3)*10^(-6);
                GMatrix(app.CNP(j,2),app.CNP(j,1))=GMatrix(app.CNP(j,2),app.CNP(j,1))-1i*app.w*app.CNP(j,3)*10^(-6);%电容单位为uF
            end
            for j=1:1:app.Lnumber
                GMatrix(app.LNP(j,1),app.LNP(j,1))=GMatrix(app.LNP(j,1),app.LNP(j,1))+1/(1i*app.w*app.LNP(j,3)*10^(-3));
                GMatrix(app.LNP(j,2),app.LNP(j,2))=GMatrix(app.LNP(j,2),app.LNP(j,2))+1/(1i*app.w*app.LNP(j,3)*10^(-3));
                GMatrix(app.LNP(j,1),app.LNP(j,2))=GMatrix(app.LNP(j,1),app.LNP(j,2))-1/(1i*app.w*app.LNP(j,3)*10^(-3));
                GMatrix(app.LNP(j,2),app.LNP(j,1))=GMatrix(app.LNP(j,2),app.LNP(j,1))-1/(1i*app.w*app.LNP(j,3)*10^(-3));%电感单位为mH
            end
            for j=1:1:app.Vnumber
                if GMatrix(app.VNP(j,1),app.VNP(j,2))~=0
                    IMatrix(app.VNP(j,1),1)=IMatrix(app.VNP(j,1),1)-app.VNP(j,3)*GMatrix(app.VNP(j,1),app.VNP(j,2));
                end
                if GMatrix(app.VNP(j,2),app.VNP(j,1))~=0
                    IMatrix(app.VNP(j,2),1)=IMatrix(app.VNP(j,2),1)+app.VNP(j,3)*GMatrix(app.VNP(j,2),app.VNP(j,1));
                end
                if GMatrix(app.VNP(j,1),app.VNP(j,2))==0
                    GMatrix(app.VNP(j,2),:)=GMatrix(app.VNP(j,1),:)+GMatrix(app.VNP(j,2),:);
                    GMatrix(app.VNP(j,1),:)=0;
                    IMatrix(app.VNP(j,1),:)=0;
                    tracer=app.VNP(j,1);
                    GMatrix(tracer,app.VNP(j,1))=GMatrix(tracer,app.VNP(j,1))+1;
                    GMatrix(tracer,app.VNP(j,2))=GMatrix(tracer,app.VNP(j,2))-1;
                    IMatrix(tracer,1)=IMatrix(tracer,1)+app.VNP(j,3);
                end
            end
            for j=1:1:app.Inumber
                IMatrix(app.INP(j,2),1)=IMatrix(app.INP(j,2),1)+app.INP(j,3);%终止点
                IMatrix(app.INP(j,1),1)=IMatrix(app.INP(j,1),1)-app.INP(j,3);%起始点
            end
            GMatrix2=zeros(N-1,N-1);
            for f=1:1:N-1
                for ff=1:1:N-1
                    GMatrix2(f,ff)=GMatrix(f,ff);
                end
            end
            IMatrix2=zeros(N-1,1);
            for m=1:1:N-1
                IMatrix2(m,1)=IMatrix(m,1);
            end
            UMatrix=pinv(GMatrix2)*IMatrix2;
            results=UMatrix;
        end
        
        function results = feizhengxian(app)
            N=app.number;
            GMatrix=sym(zeros(N,N));
            IMatrix=sym(zeros(N,1));
            
            for j=1:1:app.Rnumber
                GMatrix(app.RNP(j,1),app.RNP(j,1))=GMatrix(app.RNP(j,1),app.RNP(j,1))+sym(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,2))=GMatrix(app.RNP(j,2),app.RNP(j,2))+sym(1/app.RNP(j,3));
                GMatrix(app.RNP(j,1),app.RNP(j,2))=GMatrix(app.RNP(j,1),app.RNP(j,2))-sym(1/app.RNP(j,3));
                GMatrix(app.RNP(j,2),app.RNP(j,1))=GMatrix(app.RNP(j,2),app.RNP(j,1))-sym(1/app.RNP(j,3));
            end
            for j=1:1:app.Cnumber
                GMatrix(app.CNP(j,1),app.CNP(j,1))=GMatrix(app.CNP(j,1),app.CNP(j,1))+finverse(str2sym(app.fEditField.Value))*sym(app.CNP(j,3)*10^(-6));
                GMatrix(app.CNP(j,2),app.CNP(j,2))=GMatrix(app.CNP(j,2),app.CNP(j,2))+finverse(str2sym(app.fEditField.Value))*sym(app.CNP(j,3)*10^(-6));
                GMatrix(app.CNP(j,1),app.CNP(j,2))=GMatrix(app.CNP(j,1),app.CNP(j,2))-finverse(str2sym(app.fEditField.Value))*sym(app.CNP(j,3)*10^(-6));
                GMatrix(app.CNP(j,2),app.CNP(j,1))=GMatrix(app.CNP(j,2),app.CNP(j,1))-finverse(str2sym(app.fEditField.Value))*sym(app.CNP(j,3)*10^(-6));%电容单位为uF
            end
            for j=1:1:app.Lnumber
                GMatrix(app.LNP(j,1),app.LNP(j,1))=GMatrix(app.LNP(j,1),app.LNP(j,1))+1/(finverse(str2sym(app.fEditField.Value))*sym(app.LNP(j,3)*10^(-3)));
                GMatrix(app.LNP(j,2),app.LNP(j,2))=GMatrix(app.LNP(j,2),app.LNP(j,2))+1/(finverse(str2sym(app.fEditField.Value))*sym(app.LNP(j,3)*10^(-3)));
                GMatrix(app.LNP(j,1),app.LNP(j,2))=GMatrix(app.LNP(j,1),app.LNP(j,2))-1/(finverse(str2sym(app.fEditField.Value))*sym(app.LNP(j,3)*10^(-3)));
                GMatrix(app.LNP(j,2),app.LNP(j,1))=GMatrix(app.LNP(j,2),app.LNP(j,1))-1/(finverse(str2sym(app.fEditField.Value))*sym(app.LNP(j,3)*10^(-3)));%电感单位为mH
            end
            for j=1:1:app.Vnumber
                if GMatrix(app.VNP(j,1),app.VNP(j,2))~=0
                    IMatrix(app.VNP(j,1),1)=IMatrix(app.VNP(j,1),1)-app.VNP(j,3)*GMatrix(app.VNP(j,1),app.VNP(j,2));
                end
                if GMatrix(app.VNP(j,2),app.VNP(j,1))~=0
                    IMatrix(app.VNP(j,2),1)=IMatrix(app.VNP(j,2),1)+app.VNP(j,3)*GMatrix(app.VNP(j,2),app.VNP(j,1));
                end
                if GMatrix(app.VNP(j,1),app.VNP(j,2))==0
                    GMatrix(app.VNP(j,2),:)=GMatrix(app.VNP(j,1),:)+GMatrix(app.VNP(j,2),:);
                    GMatrix(app.VNP(j,1),:)=0;
                    IMatrix(app.VNP(j,1),:)=0;
                    tracer=app.VNP(j,1);
                    GMatrix(tracer,app.VNP(j,1))=GMatrix(tracer,app.VNP(j,1))+1;
                    GMatrix(tracer,app.VNP(j,2))=GMatrix(tracer,app.VNP(j,2))-1;
                    IMatrix(tracer,1)=IMatrix(tracer,1)+app.VNP(j,3);
                end
            end
            for j=1:1:app.Inumber
                IMatrix(app.INP(j,2),1)=IMatrix(app.INP(j,2),1)+app.INP(j,3);%终止点
                IMatrix(app.INP(j,1),1)=IMatrix(app.INP(j,1),1)-app.INP(j,3);%起始点
            end
            
            GMatrix2=sym(zeros(N-1,N-1));
            for f=1:1:N-1
                for ff=1:1:N-1
                    GMatrix2(f,ff)=GMatrix(f,ff);
                end
            end
            IMatrix2=sym(zeros(N-1,1));
            for m=1:1:N-1
                IMatrix2(m,1)=IMatrix(m,1);
            end
            UMatrix=inv(subs(GMatrix2,'t',app.ss))*IMatrix2;
            results=UMatrix;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data=app.t;
            app.UITable2.Data=app.u;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            name=app.nameEditField.Value;
            node1=app.node1EditField.Value;
            node2=app.node2EditField.Value;
            value=app.valueEditField.Value;
            initialphase=app.initialphaseEditField.Value;
            nt=[node1 node2 value];
            if name=="电容"
                W=1;
            end
            if name=="电感"
                W=2;
            end
            if name=="电阻"
                W=0;
            end
            switch W
                case 0
            app.RNP=[app.r;nt];
            app.r=app.RNP;
            app.Rnumber=app.Rnumber+1;
                case 1
            app.CNP=[app.c;nt];
            app.c=app.CNP;
            app.Cnumber=app.Cnumber+1;
                case 2
            app.LNP=[app.l;nt];
            app.l=app.LNP;
            app.Lnumber=app.Lnumber+1;
            end
            nr={name node1 node2 value initialphase};
            app.UITable.Data=[app.t;nr];
            app.t=app.UITable.Data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            ln=app.LinenumberEditField_2.Value;
            app.UITable.Data(ln,:)=[];%只能最后再进行删除操作
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %创建文件选择对话框选择指定图像
            [file,path]=uigetfile('*.jpg');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                img=imread(fullfile(path,file));%读取图像
                imshow(img,'Parent',app.UIAxes) %将图像显示在指定坐标轴
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [filename, pathname, ~] = uiputfile( ...
             {'*.xls;*.xlsx','All Excel Files (*.xls;*.xlsx)';}, 'Save as','result');
            if isequal(filename,0) || isequal(pathname,0)
                disp('取消保存');
            else
                for j=1:1:app.numberEditField.Value-1
                    nn={j,app.UITable2.Data(j,2)};
                    data=[app.n;nn];
                app.n=data;
                end
                title={'n','value'};
                result=[title;data];
                xlswrite(fullfile(pathname,filename),result);
                disp('表格导出成功');
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.valueEditField.Value~=0
            name=app.nameEditField.Value;
            node1=app.node1EditField.Value;
            node2=app.node2EditField.Value;
            value=app.valueEditField.Value;
            initialphase=app.initialphaseEditField.Value;
            nr={name node1 node2 value initialphase};
            app.UITable.Data=[app.t;nr];
            app.t=app.UITable.Data;
            v=value*exp(1i*initialphase*pi/180);
            if app.node1EditField_2.Value==0
            nw=[node1 node2 v];
            app.VNP=[app.uu;nw];
            app.uu=app.VNP;
            app.Vnumber=app.Vnumber+1;
            end
            if app.node1EditField_2.Value~=0&&app.sEditField.Value=="电源"
                nww=[node1 node2 app.node1EditField_2.Value app.node2EditField_2.Value v];
                app.VINP=[app.vi;nww];
                app.vi=app.VINP;
                app.VInumber=app.VInumber+1;
            end
             if app.node1EditField_2.Value~=0&&app.sEditField.Value=="电压"
                nwww=[node1 node2 app.node1EditField_2.Value app.node2EditField_2.Value v];
                app.VVNP=[app.vv;nwww];
                app.vv=app.VVNP;
                app.VVnumber=app.VVnumber+1;
             end
            end
            if app.valueEditField.Value==0
                name=app.nameEditField.Value;
                node1=app.node1EditField.Value;
                node2=app.node2EditField.Value;
                value=app.fEditField.Value;
                initialphase=app.initialphaseEditField.Value;
                nr={name node1 node2 value initialphase};
                app.UITable.Data=[app.t;nr];
                app.t=app.UITable.Data;
                str1=app.fEditField.Value;
                f1=str2sym(str1);
                syms s
                fh=laplace(f1,s);
                fh=subs(fh,s,app.ss);
                nw=[node1 node2 fh];
                app.VNP=[app.uu;nw];
                app.uu=app.VNP;
                app.Vnumber=app.Vnumber+1;
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.valueEditField.Value~=0
            name=app.nameEditField.Value;
            node1=app.node1EditField.Value;
            node2=app.node2EditField.Value;
            value=app.valueEditField.Value;
            initialphase=app.initialphaseEditField.Value;
            nr={name node1 node2 value initialphase};
            app.UITable.Data=[app.t;nr];
            app.t=app.UITable.Data;
            v=value*exp(1i*initialphase*pi/180);
            if app.node1EditField_2.Value==0
            nk=[node1 node2 v];
            app.INP=[app.ii;nk];
            app.ii=app.INP;
            app.Inumber=app.Inumber+1;
            end
            if app.node1EditField_2.Value~=0&&app.sEditField.Value=="电源"
                nkk=[node1 node2 app.node1EditField_2.Value app.node2EditField_2.Value v];
                app.IINP=[app.iii;nkk];
                app.iii=app.IINP;
                app.IInumber=app.IInumber+1;
            end
             if app.node1EditField_2.Value~=0&&app.sEditField.Value=="电压"
                nkkk=[node1 node2 app.node1EditField_2.Value app.node2EditField_2.Value v];
                app.IVNP=[app.iv;nkkk];
                app.iv=app.IVNP;
                app.IVnumber=app.IVnumber+1;
             end
            end
            if app.valueEditField.Value==0
              
                name=app.nameEditField.Value;
                node1=app.node1EditField.Value;
                node2=app.node2EditField.Value;
                value=app.valueEditField.Value;
                initialphase=app.initialphaseEditField.Value;
                nr={name node1 node2 value initialphase};
                app.UITable.Data=[app.t;nr];
                app.t=app.UITable.Data;
                str1=app.fEditField.Value;
                f1=str2sym(str1);
                syms s
                fh=laplace(f1,s);
                fh=subs(fh,s,app.ss);
                nk=[node1 node2 fh];
                app.INP=[app.ii;nk];
                app.ii=app.INP;
                app.Inumber=app.Inumber+1;
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.fEditField.Value==0
            mo=sqrt(real(app.U(app.EditField.Value,1))^2+imag(app.U(app.EditField.Value,1))^2);
            if real(app.U(app.EditField.Value,1))~=0
            o=atan(imag(app.U(app.EditField.Value,1))/real(app.U(app.EditField.Value,1)));
            end
            if real(app.U(app.EditField.Value,1))==0&&imag(app.U(app.EditField.Value,1))>0
                o=pi/2;
            end
            if real(app.U(app.EditField.Value,1))==0&&imag(app.U(app.EditField.Value,1))<0
                o=3*pi/2;
            end
            if real(app.U(app.EditField.Value,1))<0&&imag(app.U(app.EditField.Value,1))<=0
                o=o+pi;
            end
            if real(app.U(app.EditField.Value,1))<0&&imag(app.U(app.EditField.Value,1))>0
                o=o+pi;
            end
            x=0:0.01/100:0.04;
            y=mo*sqrt(2)*cos(app.w*x+o);
            plot(app.UIAxes2,x,y);
            else
                syms tt
                a=app.EditField.Value;
                y5=simplify(app.U(a,1));
                y5=eval(['@(x)',vectorize(y5)]);
                y6=simplify(ilaplace(y5(tt)));
                y7=eval(['@(t)',vectorize(y6)]);
                t=0:0.0001:100;
                y8=func2str(y7);
                y9=string(y8);
                plot(app.UIAxes2,t,y7(t));
                
            end%非正弦函数最后输
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.number=app.numberEditField.Value;
            app.U=zhiliu(app);
            for a=1:app.numberEditField.Value-1
                app.UITable2.Data(a,1)=a;
                app.UITable2.Data(a,2)=app.U(a,1);
            end 
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.number=app.numberEditField.Value;
            app.w=app.wEditField.Value;
            app.U=zhengxian(app);
            for a=1:app.numberEditField.Value-1
                app.UITable2.Data(a,1)=a;
                app.UITable2.Data(a,2)=app.U(a,1);
            end 
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
             app.number=app.numberEditField.Value;
                app.U=feizhengxian(app);
                syms tt
                for a=1:app.numberEditField.Value-1
                    y5=simplify(app.U(a,1));
                    y5=eval(['@(x)',vectorize(y5)]);
                    y6=simplify(ilaplace(y5(tt)));
                    y7=eval(['@(t)',vectorize(y6)]);
                    t=0:0.0001:100;
                    y8=func2str(y7);
                    y9=string(y8);
                    plot(app.UIAxes2,t,y7(t));
                    hold(app.UIAxes2,'on')
                    value=y8;
                    nr={a value};
                    app.UITable2.Data=[app.u;nr];
                    app.u=app.UITable2.Data;
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '电压随时间变化图像')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'name'; 'node1'; 'node2'; 'value/控制系数'; 'initialphase'};
            app.UITable.RowName = {};
            app.node1node1node2Panel = uipanel(app.UIFigure);
            app.node1node1node2Panel.Title = '请按结点标号顺序输入，标号最大值为接地点，激励有效值请根据node1标清正负（node1>node2）';
            app.node1EditFieldLabel = uilabel(app.node1node1node2Panel);
            app.node1EditFieldLabel.Text = 'node1';
            app.node1EditField = uieditfield(app.node1node1node2Panel, 'numeric');
            app.node2EditFieldLabel = uilabel(app.node1node1node2Panel);
            app.node2EditFieldLabel.Text = 'node2';
            app.node2EditField = uieditfield(app.node1node1node2Panel, 'numeric');
            app.Button = uibutton(app.node1node1node2Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '普通元件输入';
            app.LinenumberEditField_2Label = uilabel(app.node1node1node2Panel);
            app.LinenumberEditField_2Label.Text = 'Linenumber';
            app.LinenumberEditField_2 = uieditfield(app.node1node1node2Panel, 'numeric');
            app.Button_2 = uibutton(app.node1node1node2Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '删除';
            app.initialphaseEditFieldLabel = uilabel(app.node1node1node2Panel);
            app.initialphaseEditFieldLabel.Text = 'initial phase';
            app.initialphaseEditField = uieditfield(app.node1node1node2Panel, 'numeric');
            app.Button_8 = uibutton(app.node1node1node2Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '电压源输入';
            app.Button_9 = uibutton(app.node1node1node2Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '电流源输入';
            app.valueLabel = uilabel(app.node1node1node2Panel);
            app.valueLabel.Text = 'value/控制系数';
            app.valueEditField = uieditfield(app.node1node1node2Panel, 'numeric');
            app.nameEditFieldLabel = uilabel(app.node1node1node2Panel);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.node1node1node2Panel, 'text');
            app.Label_2 = uilabel(app.node1node1node2Panel);
            app.Label_2.Text = '非正弦周期信号表达式';
            app.fEditField = uieditfield(app.node1node1node2Panel, 'text');
            app.node1EditField_2Label = uilabel(app.node1node1node2Panel);
            app.node1EditField_2Label.Text = '控制源node1';
            app.node1EditField_2 = uieditfield(app.node1node1node2Panel, 'numeric');
            app.node2EditField_2Label = uilabel(app.node1node1node2Panel);
            app.node2EditField_2Label.Text = '控制源node2';
            app.node2EditField_2 = uieditfield(app.node1node1node2Panel, 'numeric');
            app.Label_3 = uilabel(app.node1node1node2Panel);
            app.Label_3.Text = '受控源';
            app.sEditField = uieditfield(app.node1node1node2Panel, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '电路图基本信息';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '电路图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '选择电路图';
            app.numberEditFieldLabel = uilabel(app.Panel);
            app.numberEditFieldLabel.Text = 'number';
            app.numberEditField = uieditfield(app.Panel, 'numeric');
            app.wEditFieldLabel = uilabel(app.Panel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.Panel, 'numeric');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'n'; 'value'};
            app.UITable2.RowName = {};
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '周期信号绘图';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '结点';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '直流';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '正弦';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '非正弦';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '导出结果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Circuit_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end